classdef planarPlotTestN1App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Spinner2_3                      matlab.ui.control.Spinner
        Spinner2Label_3                 matlab.ui.control.Label
        Slider3_3                       matlab.ui.control.Slider
        Slider3Label_3                  matlab.ui.control.Label
        Spinner2_2                      matlab.ui.control.Spinner
        Spinner2Label_2                 matlab.ui.control.Label
        Slider3_2                       matlab.ui.control.Slider
        Slider3Label_2                  matlab.ui.control.Label
        Spinner2                        matlab.ui.control.Spinner
        Spinner2Label                   matlab.ui.control.Label
        StartPlotButton                 matlab.ui.control.Button
        Slider3                         matlab.ui.control.Slider
        Slider3Label                    matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        SimulationTimeEditField         matlab.ui.control.NumericEditField
        SimulationTimeEditFieldLabel    matlab.ui.control.Label
        EnableSimulationPacingCheckBox  matlab.ui.control.CheckBox
        SimulationPacingSpinner         matlab.ui.control.Spinner
        SimulationPacingSpinnerLabel    matlab.ui.control.Label
        SimulationControls              simulink.ui.control.SimulationControls
        TimeScope                       matlab.ui.scope.TimeScope
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        graphPlotTimer;% Description
    end
    
    methods (Access = private)
        
        function graphPlotTimerFcn(app,~,~)%fetches joint values from simulation to plot in simulation
            pause(app.Simulation);%Pauses simulation to obtain current signal values
            jointDisplacement1Val = app.Simulation.SimulationOutput.jointDisplacement1.Data(end);%fetching values
            jointDisplacement2Val = app.Simulation.SimulationOutput.jointDisplacement2.Data(end);
            jointDisplacement3Val = app.Simulation.SimulationOutput.jointDisplacement3.Data(end);
            jointTheta1Val = app.Simulation.SimulationOutput.jointTheta1.Data(end);
            jointTheta2Val = app.Simulation.SimulationOutput.jointTheta2.Data(end);
            jointTheta3Val = app.Simulation.SimulationOutput.jointTheta3.Data(end);
            totalLengthVal = app.Simulation.SimulationOutput.totalLength.Data(end);
            
            x1 = jointDisplacement1Val;%plotting joint coordinates
            y1 = 0;
            x2 = x1+cos(jointTheta1Val)*(jointDisplacement2Val-jointDisplacement1Val);
            y2 = y1 + sin(jointTheta1Val)*(jointDisplacement2Val-jointDisplacement1Val);
            x3 = x2+cos(jointTheta2Val)*(jointDisplacement3Val-jointDisplacement1Val);
            y3 = y2 + sin(jointTheta2Val)*(jointDisplacement3Val-jointDisplacement1Val);
            endx = x3+cos(jointTheta3Val)*(totalLengthVal - jointDisplacement3Val);
            endy = y3 +sin(jointTheta3Val)*(totalLengthVal - jointDisplacement3Val);
            %clf;
            plot(app.UIAxes,[0 x1], [0 y1]);%Plotting Joint Coordinates
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,[x1 x2], [y1 y2]);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,[x2 x3], [y2 y3]);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, [x3 endx], [y3 endy]);
            hold(app.UIAxes, 'off');
            resume(app.Simulation);%resume simulation
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XLim = [-1 4];
            app.UIAxes.YLim = [-2 2];

            app.Simulation.setModelParameter("ReturnWorkspaceOutputs", "on");
            
            
            app.graphPlotTimer = timer("ExecutionMode","fixedRate", "Period", 1, "BusyMode","queue", "TimerFcn",@app.graphPlotTimerFcn);
            %start(app.graphPlotTimer);
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: StartPlotButton
        function StartPlotButtonPushed(app, event)
            if strcmp(app.graphPlotTimer.Running,"off")
                start(app.graphPlotTimer);
            else
                %stop(app.graphPlotTimer);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.graphPlotTimer);
            delete(app.graphPlotTimer);
            delete(app);
            
        end

        % Value changed function: SimulationPacingSpinner
        function SimulationPacingSpinnerValueChanged(app, event)
            value = app.SimulationPacingSpinner.Value;
            app.Simulation.setModelParameter('PacingRate', num2str(value));
            
            
        end

        % Value changed function: SimulationTimeEditField
        function SimulationTimeEditFieldValueChanged(app, event)
            value = app.SimulationTimeEditField.Value;
            app.Simulation.setModelParameter('StopTime', num2str(value));
            
        end

        % Value changed function: EnableSimulationPacingCheckBox
        function EnableSimulationPacingCheckBoxValueChanged(app, event)
            value = app.EnableSimulationPacingCheckBox.Value;
            if value
                result = 'on';
            else
                result = 'off';
            end

            app.Simulation.setModelParameter('EnablePacing', result);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vine Body Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.LegendNames = {'Integrator:1'};
            app.TimeScope.Title = 'Vine Length';
            app.TimeScope.YLabel = 'Length (m)';
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.SimulationPacingSpinnerLabel = uilabel(app.UIFigure);
            app.SimulationPacingSpinnerLabel.Text = 'Simulation Pacing';
            app.SimulationPacingSpinner = uispinner(app.UIFigure);
            app.SimulationPacingSpinner.ValueChangedFcn = createCallbackFcn(app, @SimulationPacingSpinnerValueChanged, true);
            app.EnableSimulationPacingCheckBox = uicheckbox(app.UIFigure);
            app.EnableSimulationPacingCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableSimulationPacingCheckBoxValueChanged, true);
            app.EnableSimulationPacingCheckBox.Text = 'Enable Simulation Pacing';
            app.SimulationTimeEditFieldLabel = uilabel(app.UIFigure);
            app.SimulationTimeEditFieldLabel.Text = 'Simulation Time';
            app.SimulationTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulationTimeEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationTimeEditFieldValueChanged, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Nn';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 0.01];
            app.Slider.Value = 0.002;
            app.Slider3Label = uilabel(app.UIFigure);
            app.Slider3Label.Text = 'jointTheta1';
            app.Slider3 = uislider(app.UIFigure);
            app.Slider3.Limits = [-2.0943951023932 2.0943951023932];
            app.Slider3.Value = -1;
            app.StartPlotButton = uibutton(app.UIFigure, 'push');
            app.StartPlotButton.ButtonPushedFcn = createCallbackFcn(app, @StartPlotButtonPushed, true);
            app.StartPlotButton.Text = 'Start Plot';
            app.Spinner2Label = uilabel(app.UIFigure);
            app.Spinner2Label.Text = 'jointOmega1';
            app.Spinner2 = uispinner(app.UIFigure);
            app.Spinner2.Step = 0.1;
            app.Spinner2.Limits = [-1 1];
            app.Slider3Label_2 = uilabel(app.UIFigure);
            app.Slider3Label_2.Text = 'jointTheta2';
            app.Slider3_2 = uislider(app.UIFigure);
            app.Slider3_2.Limits = [-2.0943951023932 2.0943951023932];
            app.Slider3_2.Value = 1.2;
            app.Spinner2Label_2 = uilabel(app.UIFigure);
            app.Spinner2Label_2.Text = 'jointOmega2';
            app.Spinner2_2 = uispinner(app.UIFigure);
            app.Spinner2_2.Step = 0.1;
            app.Spinner2_2.Limits = [-1 1];
            app.Slider3Label_3 = uilabel(app.UIFigure);
            app.Slider3Label_3.Text = 'jointTheta3';
            app.Slider3_3 = uislider(app.UIFigure);
            app.Slider3_3.Limits = [-2.0943951023932 2.0943951023932];
            app.Slider3_3.Value = -0.5;
            app.Spinner2Label_3 = uilabel(app.UIFigure);
            app.Spinner2Label_3.Text = 'jointOmega3';
            app.Spinner2_3 = uispinner(app.UIFigure);
            app.Spinner2_3.Step = 0.1;
            app.Spinner2_3.Limits = [-1 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Integrator:1'], app.TimeScope);
            bind(app.Spinner2, 'Value', app.Simulation.TunableVariables, ['jointOmega1:' app.Simulation.ModelName]);
            bind(app.Spinner2_2, 'Value', app.Simulation.TunableVariables, ['jointOmega2:' app.Simulation.ModelName]);
            bind(app.Spinner2_3, 'Value', app.Simulation.TunableVariables, ['jointOmega3:' app.Simulation.ModelName]);
            bind(app.Slider3, 'Value', app.Simulation.TunableVariables, ['jointTheta1:' app.Simulation.ModelName]);
            bind(app.Slider3_2, 'Value', app.Simulation.TunableVariables, ['jointTheta2:' app.Simulation.ModelName]);
            bind(app.Slider3_3, 'Value', app.Simulation.TunableVariables, ['jointTheta3:' app.Simulation.ModelName]);
            bind(app.Slider, 'Value', app.Simulation.TunableVariables, ['Nn:' app.Simulation.ModelName]);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = planarPlotTestN1App

            % Associate the Simulink Model
            app.Simulation = simulation('planarPlotTestN1');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end